classdef nsMeta < matlab.apps.AppBase
    %NSMETA View and Edit Meta Data for Neurostim Experiments
    %   The gui scans Neurostim data files in a folder and shows the meta
    %   data associated with each experiment. 
    %   
    %   The user can update the meta data, create new meta data, and then
    %   update the information stored in the folder.
    %   
    %   Changes to the subject or paradigm metadata will cause files to be
    %   renamed. Other metadata only update the JSON file associated with
    %   each Neurostim experiment

    % Properties that correspond to app components
    properties (Access = public)
        NeurostimMetaDataEditorUIFigure  matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        ChangeRootFolderMenu    matlab.ui.container.Menu
        Day                     matlab.ui.control.DropDown
        DayLabel                matlab.ui.control.Label
        Month                   matlab.ui.control.DropDown
        MonthLabel              matlab.ui.control.Label
        Year                    matlab.ui.control.DropDown
        YearDropDownLabel       matlab.ui.control.Label
        PreviousDay             matlab.ui.control.Button
        NextDay                 matlab.ui.control.Button
        AddMetaFieldSubject     matlab.ui.control.Button
        SubjectMetaData         matlab.ui.control.Table
        SubjectsLabel           matlab.ui.control.Label
        AddMetaFieldSession     matlab.ui.control.Button
        SessionMetaData         matlab.ui.control.Table
        SessionsLabel           matlab.ui.control.Label
        ExperimentsLabel        matlab.ui.control.Label
        Paradigm                matlab.ui.control.EditField
        ParadigmEditFieldLabel  matlab.ui.control.Label
        Subject                 matlab.ui.control.EditField
        SubjectEditFieldLabel   matlab.ui.control.Label
        DryRunCheckBox          matlab.ui.control.CheckBox
        AddMetaField            matlab.ui.control.Button
        Update                  matlab.ui.control.Button
        Refresh                 matlab.ui.control.Button
        Root                    matlab.ui.control.EditField
        DataRootEditFieldLabel  matlab.ui.control.Label
        ExperimentMetaData      matlab.ui.control.Table
    end


    properties (Access = private)
        originalExperimentMetaData % The Experiment meta data as read from the files on disk
        originalSessionMetaData % The session meta data as read from disk
        originalSubjectMetaData % The subject meta data as read from disk

        savedStateFields = {'Root','Year','Month','Day','years','months','day'}; % Fields whose state will be saved across runs.

        years
        months
        days
    end

    methods (Access = private)
        function writeJson(~,str,file)
            %Utility to write readable json files
            str = jsonencode(str,"PrettyPrint",true);
            fid  = fopen(file,'wt');
            fprintf(fid,'%s',str);
            fclose(fid);
        end

        function thisJson= readJson(~,file)
            % Utility to read json files and change all numeric values to
            % strings
            fid =fopen(file,'r');
            txt = fread(fid,inf,'*char');
            fclose(fid);
            thisJson = jsondecode(txt');
            fn = fieldnames(thisJson);
            for j=1:numel(thisJson)
                for i=1:numel(fn)
                    if isnumeric(thisJson(j).(fn{i})) || ischar(thisJson(j).(fn{i}))
                        thisJson(j).(fn{i}) = string(num2str(thisJson(j).(fn{i})));
                    end
                end
            end
        end

        function refreshUI(app,target)
            % Updatae the UI Table based on the new content (called after
            % loading new data)
            if nargin<2
                targets = {'Experiment','Session','Subject'};
                for i=1:numel(targets)
                    refreshUI(app,targets{i});
                end
                return;
            end

            switch (target)
                case 'Experiment'
                    if isempty(app.ExperimentMetaData.Data)
                        app.ExperimentMetaData.Enable =   "off";
                        app.ExperimentMetaData.ColumnName = {'No Neurostim experiment data found. Change root, date, or push refresh.'};
                    else
                        app.ExperimentMetaData.Enable =   "on";
                        app.ExperimentMetaData.ColumnName = app.ExperimentMetaData.Data.Properties.VariableNames;
                        allTrue = true(ones(1,width(app.ExperimentMetaData.Data)));
                        app.ExperimentMetaData.ColumnSortable = allTrue;
                        app.ExperimentMetaData.ColumnEditable = allTrue & ~ismember(app.ExperimentMetaData.Data.Properties.VariableNames,{'startTime'});
                    end
                case 'Session'
                    % Updatae the Session UI Table based on the new content (called after
                    % loading new data)
                    if isempty(app.SessionMetaData.Data)
                        app.SessionMetaData.ColumnName = {'No session meta data found. Add columns with the Add Meta Field button.'};
                    else
                        app.SessionMetaData.ColumnName = app.SessionMetaData.Data.Properties.VariableNames;
                        allTrue = true(ones(1,width(app.SessionMetaData.Data)));
                        app.SessionMetaData.ColumnSortable = allTrue;
                        app.SessionMetaData.ColumnEditable = allTrue  & ~ismember(app.ExperimentMetaData.Data.Properties.VariableNames,{'subject'});
                    end
                case 'Subject'
                    % Updatae the Subject UI Table based on the new content (called after
                    % loading new data)
                    if isempty(app.SubjectMetaData.Data)
                        app.SubjectMetaData.ColumnName = {'No subject meta data found.'};
                    else
                        app.SubjectMetaData.ColumnName = app.SubjectMetaData.Data.Properties.VariableNames;
                        allTrue = true(ones(1,width(app.SubjectMetaData.Data)));
                        app.SubjectMetaData.ColumnSortable = allTrue;
                        app.SubjectMetaData.ColumnEditable = allTrue  & ~ismember(app.ExperimentMetaData.Data.Properties.VariableNames,{'subject'});
                    end
            end
        end

        function saveState(app)
            % Save state of the GUI. (Called on exit)
            for i=1:numel(app.savedStateFields)
                if isa(app.(app.savedStateFields{i}), 'matlab.ui.control.internal.model.mixin.PlaceholderComponent')
                    setpref('nsMeta',app.savedStateFields{i},app.(app.savedStateFields{i}).Value);
                else
                    setpref('nsMeta',app.savedStateFields{i},app.(app.savedStateFields{i}));
                end
            end
        end

        function loadState(app)
            % Load previous state of the GUI (called on creation)
            for i=1:numel(app.savedStateFields)
                if ispref('nsMeta',app.savedStateFields{i})
                    if isa(app.(app.savedStateFields{i}), 'matlab.ui.control.internal.model.mixin.PlaceholderComponent')
                        app.(app.savedStateFields{i}).Value = getpref('nsMeta',app.savedStateFields{i});
                    else
                        app.(app.savedStateFields{i}) = getpref('nsMeta',app.savedStateFields{i});
                    end
                end
            end
        end

        function [tExperiment,tSession,tSubject] = nsMetaRead(app)
            % Read all meta data from a folder.
            root = app.Root.Value;

            if ~exist(root,'dir')
                error('Root folder %s does not exist',root)
            end
            if ispc
                root = strrep(root,'/','\');
            end
            dateFolder = [app.Year.Value '/' app.Month.Value '/' app.Day.Value];
            folder = fullfile(root,dateFolder);
            if ~exist(folder,'dir')
                error('The data folder %s does not exist',folder)
            end

            %%  Find the neurostim data files in the folder
            files= dir(fullfile(folder,'*.mat'));
            if isempty(files)
                tExperiment = [];
                tSession = [];
                tSubject = [];
            else
                pathFromRoot = strrep(files(1).folder,root,'');
                nameFromRoot = strcat(pathFromRoot,filesep,{files.name}');
                % Prune to get files that Neurostim generates
                if strcmpi(filesep','\')
                    fs = '\\';
                else
                    fs = filesep;
                end
                pattern = ['(?<date>\d{4,4}' fs '\d{2,2}' fs '\d{2,2})' fs '(?<subject>\w{1,10})\.(?<paradigm>\w+)\.(?<startTime>\d{6,6})\.'];
                metaFromFileName = regexp(nameFromRoot,pattern,'names');
                % Prune those file that did not match
                out = cellfun(@isempty,metaFromFileName);
                metaFromFileName= [metaFromFileName{~out}]; % Create a struct array with the relevant info
                nameFromRoot  = nameFromRoot(~out); %#ok<NASGU>
                files = files(~out);

                % Select on subject/paradigm edit boxes if not empty
                subject = app.Subject.Value;
                if ~isempty(subject)
                    metaFromFileName =metaFromFileName(strcmpi({metaFromFileName.subject},subject));
                end
                paradigm = app.Paradigm.Value;
                if ~isempty(paradigm)
                    metaFromFileName =metaFromFileName(strcmpi({metaFromFileName.paradigm},paradigm));
                end
                nrExperiments = numel(metaFromFileName);
                % Read associated JSON files (if they exist)
                for i=1:nrExperiments
                    metaFromFileName(i).fullName = fullfile(root,pathFromRoot,files(i).name);
                    [pth,jsonFile] = fileparts(metaFromFileName(i).fullName);
                    jsonFile = fullfile(pth,[jsonFile '.json']);
                    if exist(jsonFile,'file')
                        thisJson = readJson(app,jsonFile);
                    else
                        thisJson = struct;
                    end
                    if i==1
                        metaFromJson = thisJson;
                    else
                        metaFromJson = catstruct(1,metaFromJson,thisJson);
                    end
                end
                metaFieldsFromJson = setxor(fieldnames(metaFromJson),{'provenance'});

                %% Read the current meta definition for experiments
                definitionFile = fullfile(root,'experiment_definition.json');
                app.AddMetaField.Enable = 'on';
                if exist(definitionFile,'file')
                    json = readJson(app,definitionFile);
                    if json.Locked =="1"
                        app.AddMetaField.Enable = 'off';
                    end
                    metaFieldsFromDefinition = fieldnames(json.Fields);
                else
                    metaFieldsFromDefinition = cell(0,1);
                end

                metaFields = unique(cat(1,metaFieldsFromDefinition,metaFieldsFromJson,{'subject','paradigm','startTime'}'));
                nrMetaFields = numel(metaFields);

                % Store folder root and date in the properties so taht we
                % can reconstruct filenames later
                tExperiment = table('size',[nrExperiments nrMetaFields], 'VariableTypes',repmat({'string'},[1 nrMetaFields]),'VariableNames',metaFields);
                tExperiment =addprop(tExperiment,{'date','root'},{'table','table'}) ;
                tExperiment.Properties.CustomProperties.date  = dateFolder;
                tExperiment.Properties.CustomProperties.root = root;
                % Assign a unique ID to each row.
                tExperiment = addvars(tExperiment,strcat('#',string((1:nrExperiments)')),'NewVariableNames','id','before',1);
                tExperiment = movevars(tExperiment,{'subject','paradigm','startTime'},'After',1);

                for i=1:nrExperiments
                    for j=1:nrMetaFields
                        if isfield(metaFromJson(i),metaFields{j})
                            thisValue = metaFromJson(i).(metaFields{j});
                        elseif isfield(metaFromFileName(i),metaFields{j} )
                            thisValue = metaFromFileName(i).(metaFields{j});
                        else
                            thisValue = "";
                        end
                        tExperiment.(metaFields{j})(i) = thisValue;
                    end
                end

                tExperiment= sortrows(tExperiment,{'subject','paradigm','startTime'},'ascend');


                %% Setup Session Meta Data
                % See if there are any Session JSON files
                uSubjects = unique(tExperiment.subject);
                nrSessions = numel(uSubjects);
                metaFromJson =struct([]);
                for i=1:nrSessions
                    sessionJsonFile = fullfile(folder,[uSubjects{i} '.json']);
                    if exist(sessionJsonFile,'file')
                        thisJson = readJson(app,sessionJsonFile);
                    else
                        thisJson = struct;
                    end
                    if isempty(metaFromJson)
                        metaFromJson = thisJson;
                    else
                        metaFromJson = catstruct(1,metaFromJson,thisJson);
                    end
                end
                metaFieldsFromJson = fieldnames(metaFromJson);
                % Retrieve definition
                definitionFile = fullfile(root,'session_definition.json');
                app.AddMetaFieldSession.Enable = 'on';
                if exist(definitionFile,'file')
                    json = readJson(app,definitionFile);
                    if json.Locked =="1"
                        app.AddMetaFieldSession.Enable = 'off';
                    end
                    metaFieldsFromDefinition = fieldnames(json.Fields);
                else
                    metaFieldsFromDefinition = cell(0,1);
                end
                metaFields = unique(cat(1,metaFieldsFromDefinition,metaFieldsFromJson,{'subject'}'));
                nrMetaFields = numel(metaFields);

                % Create table and fill with current folder data
                tSession = table('size',[nrSessions nrMetaFields], 'VariableTypes',repmat({'string'},[1 nrMetaFields]),'VariableNames',metaFields);
                for i=1:nrSessions
                    for j=1:nrMetaFields
                        if isfield(metaFromJson(i),metaFields{j})
                            thisValue = metaFromJson(i).(metaFields{j});
                        elseif strcmpi(metaFields{j},'subject')
                            thisValue = uSubjects{i};
                        else
                            thisValue = "";
                        end
                        tSession.(metaFields{j})(i) = thisValue;
                    end
                end
                tSession =addprop(tSession,{'date','root'},{'table','table'}) ;
                tSession.Properties.CustomProperties.date  = dateFolder;
                tSession.Properties.CustomProperties.root = root;
                tSession= sortrows(tSession,{'subject'},'ascend');
                % Assign a unique ID to each row.
                tSession = addvars(tSession,strcat('#',string((1:nrSessions)')),'NewVariableNames','id','before',1);
                tSession =movevars(tSession,{'subject'},'after',1);


                %% Get Subject meta data
                % Both the definition and the actual subject meta data are
                % in the Root folder
                % Retrieve definition
                definitionFile = fullfile(root,'subject_definition.json');
                app.AddMetaFieldSubject.Enable = 'on';
                if exist(definitionFile,'file')
                    json = readJson(app,definitionFile);
                    if json.Locked =="1"
                        app.AddMetaFieldSubject.Enable = 'off';
                    end
                    metaFieldsFromDefinition = fieldnames(json.Fields);
                else
                    metaFieldsFromDefinition = cell(0,1);
                end

                metaDataFile =  fullfile(root,'subject.json');
                if exist(metaDataFile,'file')
                    metaFromJson = readJson(app,metaDataFile);
                    metaFieldsFromJson = fieldnames(metaFromJson);
                else
                    metaFromJson = struct;
                    metaFieldsFromJson = cell(0,1);
                end
                metaFields = unique(cat(1,metaFieldsFromDefinition,metaFieldsFromJson,{'subject'}'));
                nrMetaFields = numel(metaFields);

                % Create table and fill with current folder data
                tSubject = table('size',[nrSessions nrMetaFields], 'VariableTypes',repmat({'string'},[1 nrMetaFields]),'VariableNames',metaFields);
                for i=1:nrSessions
                    if isfield(metaFromJson,'subject')
                        thisMetaFromJson = metaFromJson(uSubjects(i)==cat(1,metaFromJson.subject));
                    else
                        thisMetaFromJson = struct;
                    end
                    for j=1:nrMetaFields
                        if isfield(thisMetaFromJson,metaFields{j})
                            thisValue =thisMetaFromJson.(metaFields{j});
                        elseif strcmpi(metaFields{j},'subject')
                            thisValue = uSubjects{i};
                        else
                            thisValue = "";
                        end
                        tSubject.(metaFields{j})(i) = thisValue;
                    end
                end
                tSubject =addprop(tSubject,{'date','root'},{'table','table'}) ;
                tSubject.Properties.CustomProperties.date  = dateFolder;
                tSubject.Properties.CustomProperties.root = root;
                tSubject= sortrows(tSubject,{'subject'},'ascend');
                % Assign a unique ID to each row.
                tSubject = addvars(tSubject,strcat('#',string((1:nrSessions)')),'NewVariableNames','id','before',1);
                tSubject = movevars(tSubject,{'subject'},'after',1);

            end
        end

        function [ok,provenance]= rename(app,folder,oldSubject,oldParadigm,oldStartTime,newSubject,newParadigm)
            % Rename a Neurostim file and its associated files (same
            % prefix); this is needed when either the subject or the
            % paradigm changes.  The stored CIC object is updated
            % accordinly, and the change is logged in the provenance field of the
            % JSON file
            if app.DryRunCheckBox.Value
                dryRunText = '[DRYRUN]';
                dryRun  = true;
            else
                dryRun  = false;
                dryRunText  = '';
            end
            provenance = '';
            ok = true;
            oldPrefix = sprintf('%s.%s.%s',oldSubject,oldParadigm,oldStartTime);
            oldFullName = fullfile(folder,[oldPrefix '.mat']);
            if ~exist(oldFullName,'file')
                ok = false;
                fprintf('File does not exist %s\n',oldFullName)
                return
            else
                load(oldFullName,'c');
            end
            % Update CIC and save to a new file.
            c.subject = newSubject;
            c.paradigm = newParadigm;
            % Create the new file name
            newPrefix = sprintf('%s.%s.%s',newSubject,newParadigm,oldStartTime);
            newFullName = fullfile(folder,[newPrefix '.mat']);
            if dryRun
                ok = true;
            else
                save(newFullName,'c');
                if exist(newFullName,'file')
                    delete(oldFullName);
                else
                    ok = false;
                end
            end
            if ok
                fprintf('%s Updated %s and renamed to %s\n',dryRunText, oldFullName, newFullName);
            else
                fprintf('Unable to create %s\n',newFullName)
                return;
            end
            %% Move associated data files (same prefix)
            files= dir(fullfile(folder,[oldPrefix '*']));
            for i=1:numel(files)
                thisOldName = fullfile(folder,files(i).name);
                thisNewName = strrep(thisOldName,oldPrefix,newPrefix);
                if dryRun
                    status=1;
                else
                    [status, message] = movefile(thisOldName,thisNewName);
                end
                if status ==0
                    fprintf(2,'Failed to move %s to %s (%s)\n',thisOldName,thisNewName,message);
                    ok = false;
                else
                    fprintf('%s \t Renamed associated file %s to %s\n',dryRunText, files(i).name, strrep(thisNewName,folder,''));
                end
            end
            provenance = jsondecode(sprintf('{"Date":"%s", "File":"%s"}',datestr(now,'yyyymmddTHHMMSS'),oldPrefix));
        end

        function out = addNewMeta(app,target,fields)
            if ischar(target) || isstring(target)
                org = app.(['original' target 'MetaData']);
                editable = app.([target 'MetaData']).Data;
                inApp = true;
            else
                org =[];
                editable =target;
                inApp = false;
            end
            nrRows = height(editable);
            for i=1:numel(fields)
                if ismember(fields{i},editable.Properties.VariableNames)
                    % Already in table. Skip
                    continue;
                end
                if inApp
                    org = addvars(org,repmat("",[nrRows,1]),'NewVariableNames',fields{i});
                end
                editable    = addvars(editable,repmat("",[nrRows,1]),'NewVariableNames',fields{i});
            end
            if inApp
                refreshUI(app,target);
                out = [];
            else
                % Return the updated table
                out = editable;
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            loadState(app);
            refreshUI(app,'Experiment');
        end

        % Button pushed function: Refresh
        function RefreshPushed(app, event)
            [app.originalExperimentMetaData,app.originalSessionMetaData,app.originalSubjectMetaData] = nsMetaRead(app);
            if isempty(app.originalExperimentMetaData)
                exptDataWithoutProvenance = app.originalExperimentMetaData;
            else
                isProvenance = ismember(app.originalExperimentMetaData.Properties.VariableNames,'provenance');
                exptDataWithoutProvenance  = app.originalExperimentMetaData(:,~isProvenance); % Editable copy in the UI.
            end
            app.ExperimentMetaData.Data =     exptDataWithoutProvenance;
            app.SessionMetaData.Data = app.originalSessionMetaData;
            app.SubjectMetaData.Data = app.originalSubjectMetaData;

            refreshUI(app)
        end

        % Button pushed function: Update
        function UpdatePushed(app, event)
            if app.DryRunCheckBox.Value
                dryRunText = '[DRYRUN]';
                dryRun  = true;
            else
                dryRun  = false;
                dryRunText  = '';
            end
            fprintf('****** Updating meta data %s *******\n',datestr(now,"HH:MM:SS"))

            newExperimentMetaData = sortrows(app.ExperimentMetaData.Data,'id');
            oldExperimentMetaData = sortrows(app.originalExperimentMetaData,'id');
            folder = fullfile(newExperimentMetaData.Properties.CustomProperties.root,newExperimentMetaData.Properties.CustomProperties.date);
            jsonFieldsExperiment = setxor(newExperimentMetaData.Properties.VariableNames,{'id','subject','paradigm','startTime'})';
            for i=1:height(oldExperimentMetaData)
                %% Check (meta) data that are stored in the file and the filename (only subject and paradigm are allowed to be changed).
                % and update if needed.
                if ~strcmpi(newExperimentMetaData{i,'subject'},oldExperimentMetaData{i,'subject'})  || ~strcmpi(newExperimentMetaData{i,'paradigm'},oldExperimentMetaData{i,'paradigm'})
                    % Have to read the file to update the CIC object
                    % inside.
                    [~,provenance] = rename(app,folder,oldExperimentMetaData.subject{i},oldExperimentMetaData.paradigm{i},oldExperimentMetaData.startTime{i},newExperimentMetaData.subject{i},newExperimentMetaData.paradigm{i});
                    jsonNeedsUpdate = true;
                else
                    %% Check whetehr the meta data that came from a JSON file changed
                    jsonNeedsUpdate = any(~cellfun(@(x) isequal(oldExperimentMetaData{i,x},newExperimentMetaData{i,x}),jsonFieldsExperiment));
                    provenance = struct;
                end

                if jsonNeedsUpdate
                    jsonFullFile =  fullfile(folder,sprintf('%s.%s.%s.json',newExperimentMetaData.subject{i},newExperimentMetaData.paradigm{i},newExperimentMetaData.startTime{i}));
                    jsonStruct = table2struct(newExperimentMetaData(i,jsonFieldsExperiment));
                    if ismember('provenance',oldExperimentMetaData.Properties.VariableNames)
                        provenance = cat(1,provenance,oldExperimentMetaData{i,'provenance'}{1});
                    end
                    if ~isempty(provenance)
                        jsonStruct.provenance = provenance;
                    end
                    if dryRun
                        %Nothing to do
                    else
                        app.writeJson(jsonStruct,jsonFullFile);
                    end
                    fprintf('%s Updated JSON file %s\n',dryRunText, jsonFullFile);
                end
            end

            %% Update session data
            oldSessionMetaData = app.originalSessionMetaData;
            newSessionMetaData = app.SessionMetaData.Data;
            jsonFieldsSession = setxor(newSessionMetaData.Properties.VariableNames,{'id','subject'})';
            for i=1:height(oldSessionMetaData)
                %% Check whetehr the meta data that came from a JSON file changed
                jsonNeedsUpdate = any(~cellfun(@(x) isequal(oldSessionMetaData{i,x},newSessionMetaData{i,x}),jsonFieldsSession));
                if jsonNeedsUpdate
                    jsonFullFile = fullfile(folder,sprintf('%s.json',newSessionMetaData.subject{i}));
                    jsonStruct = table2struct(newSessionMetaData(i,jsonFieldsSession));
                    if dryRun
                        %Nothing to do
                    else
                        app.writeJson(jsonStruct,jsonFullFile);
                    end
                    fprintf('%s Updated JSON file %s\n',dryRunText, jsonFullFile);
                end
            end
            %% Update subject data
            oldSubjectMetaData = app.originalSubjectMetaData;
            newSubjectMetaData = app.SubjectMetaData.Data;
            jsonFieldsSubject = setxor(newSubjectMetaData.Properties.VariableNames,{'id'})';
            jsonFullFile = fullfile(app.Root.Value,'subject.json');
            if exist(jsonFullFile,'file')
                jsonSubjects = readJson(app,jsonFullFile);
            else
                jsonSubjects = struct([]);
            end
            for i=1:height(oldSubjectMetaData)
                %% Check whetehr the meta data that came from a JSON file changed
                jsonNeedsUpdate = any(~cellfun(@(x) isequal(oldSubjectMetaData{i,x},newSubjectMetaData{i,x}),jsonFieldsSubject));
                if jsonNeedsUpdate
                    thisJson = table2struct(newSubjectMetaData(i,jsonFieldsSubject));
                    if isempty(jsonSubjects)
                        jsonSubjects = thisJson;
                    else
                        ix = [jsonSubjects.subject] == thisJson.subject;
                        if any(ix)
                            jsonSubjects(ix) = [];
                        end
                        if numel(jsonSubjects)==0
                            jsonSubjects =thisJson;
                        else
                            jsonSubjects = catstruct(1,jsonSubjects,thisJson);
                        end
                    end
                end
            end
            if dryRun
                %Nothing to do
            else
                app.writeJson(jsonSubjects,jsonFullFile);
            end
            fprintf('%s Updated JSON file %s\n',dryRunText, jsonFullFile);


            if dryRun
                fprintf('%s No changes were made\n',dryRunText)
            end
            fprintf('****** Updating meta data complete *******\n')
            % Force an update to get old and new metadata in sync again.
            RefreshPushed(app);
        end

        % Button pushed function: AddMetaField
        function AddMetaFieldPushed(app, event)
            % Allow user to add a meta data field. Initial values are
            % empty, but can be filled in the GUI and will then be saved
            % (only for the rows that changed from empty).
            newField = inputdlg('Fieldname?','Provide the name for a new meta data field.');
            if ~isempty(newField)
                addNewMeta(app,'Experiment',newField)
            end
        end

        % Close request function: NeurostimMetaDataEditorUIFigure
        function NeurostimMetaDataEditorUIFigureCloseRequest(app, event)
            saveState(app)
            delete(app)
        end

        % Menu selected function: ChangeRootFolderMenu
        function ChangeRootFolderMenuSelected(app, event)
            % Convenience function to select a root folder with a  gui.
            folder = uigetdir(app.Root.Value);
            if folder~=0 % folder ==0 is cancel
                app.Root.Value= folder;
            end
            app.NeurostimMetaDataEditorUIFigure.Visible = 'Off'; % Toggle to
            app.NeurostimMetaDataEditorUIFigure.Visible = 'On'; % bring to foreground
        end

        % Button pushed function: AddMetaFieldSession
        function AddMetaFieldSessionButtonPushed(app, event)
            % Allow user to add a meta data field to the Sessions table. Initial values are
            % empty, but can be filled in the GUI and will then be saved
            % (only for the rows that changed from empty).
            newField = inputdlg('Fieldname?','Provide the name for a new meta data field.');
            if ~isempty(newField)
                addNewMeta(app,'Session',newField)
            end
        end

        % Button pushed function: AddMetaFieldSubject
        function AddMetaFieldSubjectButtonPushed(app, event)
            newField = inputdlg('Fieldname?','Provide the name for a new meta data field.');
            if ~isempty(newField)
                addNewMeta(app,'Subject',newField)
            end
        end

        % Button pushed function: NextDay
        function NextDayButtonPushed(app, event)
            current = find(ismember(app.Day.Items,app.Day.Value));
            if current < numel(app.Day.Items)
                app.Day.Value = app.Day.Items{current+1};
            else
                % Last day of the month
                currentMonth =  find(ismember(app.Month.Items,app.Month.Value));
                if currentMonth < numel(app.Month.Items)
                    app.Month.Value = app.Month.Items{currentMonth+1};
                    app.MonthValueChanged;
                    app.Day.Value = app.Day.Items{1};
                else
                    % Last month of the year
                    currentYear =  find(ismember(app.Year.Items,app.Year.Value));
                    if currentYear < numel(app.Year.Items)
                        app.Year.Value = app.Year.Items{currentYear+1};
                        app.YearValueChanged;
                        app.Month.Value = app.Month.Items{1};
                        app.MonthValueChanged;
                        app.Day.Value = app.Day.Items{1};
                    else
                        fprintf('This is the end...\n')
                    end
                end
            end
            app.RefreshPushed
        end

        % Button pushed function: PreviousDay
        function PreviousDayButtonPushed(app, event)
            current = find(ismember(app.Day.Items,app.Day.Value));
            if current >1
                app.Day.Value = app.Day.Items{current-1};
            else
                % First day of the month
                currentMonth =  find(ismember(app.Month.Items,app.Month.Value));
                if currentMonth > 1
                    app.Month.Value = app.Month.Items{currentMonth-1};
                    app.MonthValueChanged;
                    app.Day.Value = app.Day.Items{end};
                else
                    % First month of the year
                    currentYear =  find(ismember(app.Year.Items,app.Year.Value));
                    if currentYear > 1
                        app.Year.Value = app.Year.Items{currentYear-1};
                        app.YearValueChanged;
                        app.Month.Value = app.Month.Items{end};
                        app.MonthValueChanged;
                        app.Day.Value = app.Day.Items{end};
                    else
                        fprintf('This is the beginning ...\n')
                    end
                end
            end
            app.RefreshPushed


        end

        % Value changed function: Root
        function RootValueChanged(app, event)
            root = app.Root.Value;
            app.years ={};
            app.months = {};
            app.days = {};
            yrs = dir([root '/*']);
            match = regexp({yrs.name},'(?<year>\d{4,4})','match');
            if isempty(match)
                return
            else
                app.years = [match{:}];
                for yr= 1:numel(app.years)
                    mnths = dir(fullfile(root,[app.years{yr} '/*']));
                    match =  regexp({mnths.name},'(?<month>\d{2,2})','match');
                    app.months{yr} =[match{:}];
                    for mo = 1:numel(app.months{yr})
                        dys= dir(fullfile(root,app.years{yr},[app.months{yr}{mo} '/*']));
                        match =  regexp({dys.name},'(?<month>\d{2,2})','match');
                        app.days{yr}{mo} = [match{:}];
                    end
                end
            end

            app.Year.Items = app.years;
            app.Year.Value = app.years(end);
            YearValueChanged(app, [])

        end

        % Value changed function: Year
        function YearValueChanged(app, event)
            value = app.Year.Value;
            app.Month.Items = app.months{ismember(app.years,value)};
            app.Month.Value  = app.Month.Items{1};
            app.MonthValueChanged;
        end

        % Value changed function: Month
        function MonthValueChanged(app, event)

            yrIx = ismember(app.years,app.Year.Value);
            moIx = ismember(app.months{yrIx},app.Month.Value);
            app.Day.Items = app.days{yrIx}{moIx};
            app.Day.Value = app.Day.Items{1};
        end

        % Value changed function: Day
        function DayValueChanged(app, event)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NeurostimMetaDataEditorUIFigure = uifigure('Visible', 'off');
            app.NeurostimMetaDataEditorUIFigure.Name = 'Neurostim Meta Data Editor';
            app.NeurostimMetaDataEditorUIFigure.CloseRequestFcn = createCallbackFcn(app, @NeurostimMetaDataEditorUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.NeurostimMetaDataEditorUIFigure);
            app.FileMenu.Text = 'File';
            app.ChangeRootFolderMenu = uimenu(app.FileMenu);
            app.ChangeRootFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeRootFolderMenuSelected, true);
            app.ChangeRootFolderMenu.Accelerator = 'R';
            app.ChangeRootFolderMenu.Text = 'Change Root Folder';
            app.ExperimentMetaData = uitable(app.NeurostimMetaDataEditorUIFigure);
            app.ExperimentMetaData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ExperimentMetaData.RowName = {};
            app.ExperimentMetaData.Tooltip = {'The meta data table.  You can sort it by clicking on one of the columns. All columsn except ''startTime'' are editable. '; ''; 'Changes to the meta data will only be made after pressing the update button. '};
            app.DataRootEditFieldLabel = uilabel(app.NeurostimMetaDataEditorUIFigure);
            app.DataRootEditFieldLabel.Text = 'Data Root';
            app.Root = uieditfield(app.NeurostimMetaDataEditorUIFigure, 'text');
            app.Root.ValueChangedFcn = createCallbackFcn(app, @RootValueChanged, true);
            app.Root.Tooltip = {'The root data folder that contains the folders named after the years.'};
            app.Root.Value = 'z:/klab/';
            app.Refresh = uibutton(app.NeurostimMetaDataEditorUIFigure, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @RefreshPushed, true);
            app.Refresh.Tooltip = {'Click to refresh the list based on the root and date settings.'};
            app.Refresh.Text = 'Refresh';
            app.Update = uibutton(app.NeurostimMetaDataEditorUIFigure, 'push');
            app.Update.ButtonPushedFcn = createCallbackFcn(app, @UpdatePushed, true);
            app.Update.Tooltip = {'Click to update the meta data as listed in the table. This may move and change data files. '};
            app.Update.Text = 'Update';
            app.AddMetaField = uibutton(app.NeurostimMetaDataEditorUIFigure, 'push');
            app.AddMetaField.ButtonPushedFcn = createCallbackFcn(app, @AddMetaFieldPushed, true);
            app.AddMetaField.Tooltip = {'Click to add a new meta data fiield.'};
            app.AddMetaField.Text = 'Add Meta Field';
            app.DryRunCheckBox = uicheckbox(app.NeurostimMetaDataEditorUIFigure);
            app.DryRunCheckBox.Tooltip = {'Select this and then press update to get a listing of changes that would be made (without any actual changes).'};
            app.DryRunCheckBox.Text = 'Dry Run';
            app.SubjectEditFieldLabel = uilabel(app.NeurostimMetaDataEditorUIFigure);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.Subject = uieditfield(app.NeurostimMetaDataEditorUIFigure, 'text');
            app.Subject.Tooltip = {'Restrict tp a single subject (Optional)'};
            app.ParadigmEditFieldLabel = uilabel(app.NeurostimMetaDataEditorUIFigure);
            app.ParadigmEditFieldLabel.Text = 'Paradigm';
            app.Paradigm = uieditfield(app.NeurostimMetaDataEditorUIFigure, 'text');
            app.Paradigm.Tooltip = {'Restrict to a specific paradigm (Optional).'};
            app.ExperimentsLabel = uilabel(app.NeurostimMetaDataEditorUIFigure);
            app.ExperimentsLabel.Text = 'Experiments';
            app.SessionsLabel = uilabel(app.NeurostimMetaDataEditorUIFigure);
            app.SessionsLabel.Text = 'Sessions';
            app.SessionMetaData = uitable(app.NeurostimMetaDataEditorUIFigure);
            app.SessionMetaData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SessionMetaData.RowName = {};
            app.AddMetaFieldSession = uibutton(app.NeurostimMetaDataEditorUIFigure, 'push');
            app.AddMetaFieldSession.ButtonPushedFcn = createCallbackFcn(app, @AddMetaFieldSessionButtonPushed, true);
            app.AddMetaFieldSession.Tooltip = {'Click to add a new meta data fiield.'};
            app.AddMetaFieldSession.Text = 'Add Meta Field';
            app.SubjectsLabel = uilabel(app.NeurostimMetaDataEditorUIFigure);
            app.SubjectsLabel.Text = 'Subjects';
            app.SubjectMetaData = uitable(app.NeurostimMetaDataEditorUIFigure);
            app.SubjectMetaData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SubjectMetaData.RowName = {};
            app.AddMetaFieldSubject = uibutton(app.NeurostimMetaDataEditorUIFigure, 'push');
            app.AddMetaFieldSubject.ButtonPushedFcn = createCallbackFcn(app, @AddMetaFieldSubjectButtonPushed, true);
            app.AddMetaFieldSubject.Tooltip = {'Click to add a new meta data fiield.'};
            app.AddMetaFieldSubject.Text = 'Add Meta Field';
            app.NextDay = uibutton(app.NeurostimMetaDataEditorUIFigure, 'push');
            app.NextDay.ButtonPushedFcn = createCallbackFcn(app, @NextDayButtonPushed, true);
            app.NextDay.Text = '>';
            app.PreviousDay = uibutton(app.NeurostimMetaDataEditorUIFigure, 'push');
            app.PreviousDay.ButtonPushedFcn = createCallbackFcn(app, @PreviousDayButtonPushed, true);
            app.PreviousDay.Text = '<';
            app.YearDropDownLabel = uilabel(app.NeurostimMetaDataEditorUIFigure);
            app.YearDropDownLabel.Text = 'Year';
            app.Year = uidropdown(app.NeurostimMetaDataEditorUIFigure);
            app.Year.ValueChangedFcn = createCallbackFcn(app, @YearValueChanged, true);
            app.MonthLabel = uilabel(app.NeurostimMetaDataEditorUIFigure);
            app.MonthLabel.Text = 'Month';
            app.Month = uidropdown(app.NeurostimMetaDataEditorUIFigure);
            app.Month.ValueChangedFcn = createCallbackFcn(app, @MonthValueChanged, true);
            app.DayLabel = uilabel(app.NeurostimMetaDataEditorUIFigure);
            app.DayLabel.Text = 'Day';
            app.Day = uidropdown(app.NeurostimMetaDataEditorUIFigure);
            app.Day.ValueChangedFcn = createCallbackFcn(app, @DayValueChanged, true);
            % Show the figure after all components are created
            app.NeurostimMetaDataEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nsMeta

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.NeurostimMetaDataEditorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.NeurostimMetaDataEditorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeurostimMetaDataEditorUIFigure)
        end
    end
end